classdef CarSystem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Button             matlab.ui.control.StateButton
        MATLABYOLOv2Label  matlab.ui.control.Label
        Image              matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            logo = imread("logo.png") ;
            app.Image.ImageSource = logo ;
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
                load YoloModel.mat
                
                %% 2.1 读取图像
                [file,path] = uigetfile("*.jpg;*.png;*.jpeg");
                filepath = fullfile(path,file);
                
                %% 2.2 目标检测
                if file ~= 0
                    img = imread(filepath);
                    app.Image.ImageSource = img;
                    [bboxes,scores] = detect(detector,img);%利用训练好Yolov2模型进行检查
                end
                scores = strcat(string(round(scores,4)*100),"%");
                %% 2.3 输出识别结果
                if(~isempty(bboxes))
                    img = insertObjectAnnotation(img,'rectangle',bboxes,scores);
                end
                app.Image.ImageSource = img;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.MATLABYOLOv2Label = uilabel(app.UIFigure);
            app.MATLABYOLOv2Label.Text = '基于MATLAB平台的YOLOv2目标检测系统';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '开始检测';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CarSystem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end